classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetinexImageEnhancementUIFigure  matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        ImplementationTab    matlab.ui.container.Tab
        OutputPanel          matlab.ui.container.Panel
        Save                 matlab.ui.control.Button
        Figure4              matlab.ui.control.UIAxes
        Figure3              matlab.ui.control.UIAxes
        Figure2              matlab.ui.control.UIAxes
        ParametersPanel      matlab.ui.container.Panel
        BetaEditField        matlab.ui.control.NumericEditField
        BetaEditFieldLabel   matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        AlphaEditFieldLabel  matlab.ui.control.Label
        BetaSlider           matlab.ui.control.Slider
        AlphaSlider          matlab.ui.control.Slider
        KernelLabel          matlab.ui.control.Label
        SigmaEditField       matlab.ui.control.NumericEditField
        SigmaEditFieldLabel  matlab.ui.control.Label
        SigmaKnob            matlab.ui.control.Knob
        KernelSizeKnob       matlab.ui.control.DiscreteKnob
        FilterDropDown       matlab.ui.control.DropDown
        FilterDropDownLabel  matlab.ui.control.Label
        InputPanel           matlab.ui.container.Panel
        Process              matlab.ui.control.Button
        BrowseRGB            matlab.ui.control.Button
        PathRGB              matlab.ui.control.Label
        Figure1              matlab.ui.control.UIAxes
        AboutTab             matlab.ui.container.Tab
        DescriptionPanel     matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_8  matlab.ui.control.Label
        VersionHistoryPanel  matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_7  matlab.ui.control.Label
        PeoplePanel          matlab.ui.container.Panel
        DistinguishedProfSosAgaiandLabel_6  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_5  matlab.ui.control.Label
        Image_6              matlab.ui.control.Image
        Image_5              matlab.ui.control.Image
        DistinguishedProfSosAgaiandLabel_4  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_3  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel_2  matlab.ui.control.Label
        DistinguishedProfSosAgaiandLabel  matlab.ui.control.Label
        Image_4              matlab.ui.control.Image
        Image_3              matlab.ui.control.Image
        Image_2              matlab.ui.control.Image
        Image                matlab.ui.control.Image
        BacklitImageVisualizationUsingRetinexTheoryLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Path
        img
        Kernel
        Sigma
        Filter
        alpha
        beta
        a
        o
        s
        newimg
        denoised
        Edge
        Fused
        Enhanced
        Temp
        grayscale
        Results
    end
    
    methods (Access = public)
        
        function H = denoising(app)
            [x,y,z] = size(app.img);
            temp = reshape(app.img,x,y*z);
            switch app.Filter
                case 'Gaussian'
                    F = fspecial('gaussian',app.Kernel,app.Sigma);
                    H = conv2(temp,F,'same');
                    H = reshape(H,x,y,z);
                    H = uint8(H);
                    H = reshape(H,x,y,z);
                case 'Bilateral'
                    H = bfilter2(app);
                    H = reshape(H,x,y,z);
                    H = uint8(H * 255);
                    H = reshape(H,x,y,z);
                case 'Mean'
                    F = fspecial('average',app.Kernel);
                    H = conv2(temp,F,'same');
                    H = reshape(H,x,y,z);
                    H = uint8(H);
                    H = reshape(H,x,y,z);
                otherwise
                    disp('Wrong Selection')
            end
        end
    end
    
    methods (Access = public)
        
        function B = bfilter2(app)
            A = double(app.img)/255;
            w = app.Kernel;
            % Verify that the input image exists and is valid.
            if ~exist('A','var') || isempty(A)
               error('Input image A is undefined or invalid.');
            end
            if ~isfloat(A) || ~sum([1,3] == size(A,3)) || ...
                  min(A(:)) < 0 || max(A(:)) > 1
               error(['Input image A must be a double precision ',...
                      'matrix of size NxMx1 or NxMx3 on the closed ',...
                      'interval [0,1].']);      
            end
            % Verify bilateral filter window size.
            if ~exist('w','var') || isempty(w) || ...
                  numel(w) ~= 1 || w < 1
               w = 5;
            end
            w = ceil(w);
            % Verify bilateral filter standard deviations.
            if ~exist('sigma','var') || isempty(sigma) || ...
                  numel(sigma) ~= 2 || sigma(1) <= 0 || sigma(2) <= 0
               sigma = [3 0.1];
            end
            % Apply either grayscale or color bilateral filtering.
            app.a = A;
            app.o = w;
            app.s(1) = sigma(1);
            app.s(2) = sigma(2);
            if size(A,3) == 1
               B = bfltGray(app);
            else
               B = bfltColor(app);
            end
        end

        function B = bfltGray(app)
            A = app.a;
            w = app.o;
            sigma_d = app.s(1);
            sigma_r = app.s(2);
            % Pre-compute Gaussian distance weights.
            [X,Y] = meshgrid(-w:w,-w:w);
            G = exp(-(X.^2+Y.^2)/(2*sigma_d^2));
            % Create waitbar.
            h = waitbar(0,'Applying bilateral filter...');
            set(h,'Name','Bilateral Filter Progress');
            % Apply bilateral filter.
            dim = size(A);
            B = zeros(dim);
            for i = 1:dim(1)
               for j = 1:dim(2)
                   % Extract local region.
                   iMin = max(i-w,1);
                   iMax = min(i+w,dim(1));
                   jMin = max(j-w,1);
                   jMax = min(j+w,dim(2));
                   I = A(iMin:iMax,jMin:jMax);
                   % Compute Gaussian intensity weights.
                   H = exp(-(I-A(i,j)).^2/(2*sigma_r^2));
                   % Calculate bilateral filter response.
                   F = H.*G((iMin:iMax)-i+w+1,(jMin:jMax)-j+w+1);
                   B(i,j) = sum(F(:).*I(:))/sum(F(:));                 
               end
               waitbar(i/dim(1));
            end
            % Close waitbar.
            close(h);
        end

        function B = bfltColor(app)
            A = app.a;
            w = app.o;
            sigma_d = app.s(1);
            sigma_r = app.s(2);
            % Convert input sRGB image to CIELab color space.
            if exist('applycform','file')
               A = applycform(A,makecform('srgb2lab'));
            else
               A = colorspace('Lab<-RGB',A);
            end
            % Pre-compute Gaussian domain weights.
            [X,Y] = meshgrid(-w:w,-w:w);
            G = exp(-(X.^2+Y.^2)/(2*sigma_d^2));
            % Rescale range variance (using maximum luminance).
            sigma_r = 100*sigma_r;
            % Create waitbar.
            h = waitbar(0,'Applying bilateral filter...');
            set(h,'Name','Bilateral Filter Progress');
            % Apply bilateral filter.
            dim = size(A);
            B = zeros(dim);
            for i = 1:dim(1)
               for j = 1:dim(2)              
                     % Extract local region.
                     iMin = max(i-w,1);
                     iMax = min(i+w,dim(1));
                     jMin = max(j-w,1);
                     jMax = min(j+w,dim(2));
                     I = A(iMin:iMax,jMin:jMax,:);                
                     % Compute Gaussian range weights.
                     dL = I(:,:,1)-A(i,j,1);
                     da = I(:,:,2)-A(i,j,2);
                     db = I(:,:,3)-A(i,j,3);
                     H = exp(-(dL.^2+da.^2+db.^2)/(2*sigma_r^2));     
                     % Calculate bilateral filter response.
                     F = H.*G((iMin:iMax)-i+w+1,(jMin:jMax)-j+w+1);
                     norm_F = sum(F(:));
                     B(i,j,1) = sum(sum(F.*I(:,:,1)))/norm_F;
                     B(i,j,2) = sum(sum(F.*I(:,:,2)))/norm_F;
                     B(i,j,3) = sum(sum(F.*I(:,:,3)))/norm_F;               
               end
               waitbar(i/dim(1));
            end
            % Convert filtered image back to sRGB color space.
            if exist('applycform','file')
               B = applycform(B,makecform('lab2srgb'));
            else  
               B = colorspace('RGB<-Lab',B);
            end
            % Close waitbar.
            close(h);
        end

        function [Fused,Edge] = Retinex(app)
            I = double(app.img)+1;
            H = app.denoised;
            app.Edge = app.beta * log(app.alpha * I) - log(double(H) + 1);
            Edge   = NormalRange(app);
            w      = double(app.img) / 255;
            Fused = uint8(w .* double(app.img) + (1 - w) .* double(Edge));
        end

        function newimg = NormalRange(app)
            Jmin = 0;
            Jmax = 255;
            frac = 1;
            temp = app.Edge;
            if ~isa(temp, 'double' )
                temp = double(temp);
            end
            Imax = max(temp(:));
            Imin = min(temp(:));
            if Imin < 0
                temp = (temp - Imin) / (abs(Imin) + Imax);
                Imin = 0;
            end
            newimg = temp - Imin;
            newimg = newimg ./ max(newimg(:)) * (Jmax - Jmin);
            newimg = newimg + Jmin;
            if frac == 1
                newimg = uint8(newimg);
            end
        end

        function [Enhanced] = QuadEqualizationSeperatedbyThresholdCorrection(app)
            I = app.Fused;
            app.Temp = I;
            [t(2)] = ThresholdCorrectionSos01(app);
            clear app.Temp
            app.Temp = I(I<=t(2));
            [t(1)] = ThresholdCorrectionSos01(app);
            clear app.Temp
            app.Temp = I(I> t(2));
            [t(3)] = ThresholdCorrectionSos01(app);
            clear app.Temp
            nn = imhist(I);
            I = double(I);
            p1 = nn(1:t(1)) / sum(nn(1:t(1)));
            c1 = cumsum(p1);
            p2 = nn(t(1)+1:t(2)) / sum(nn(t(1)+1:t(2)));
            c2 = cumsum(p2);
            p3 = nn(t(2)+1:t(3)) / sum(nn(t(2)+1:t(3)));
            c3 = cumsum(p3);            
            p4 = nn(t(3)+1:end) / sum(nn(t(3)+1:end));
            c4 = cumsum(p4);
            newlevel_x1 = 0 + (t(1) - 0) * c1;
            newlevel_x2 = (t(1)+1) + (t(2) - t(1)+1) * c2;
            newlevel_x3 = (t(2)+1) + (t(3) - t(2)+1) * c3;
            newlevel_x4 = (t(3)+1) + (255  - t(3)+1) * c4;
            newlevel = uint8(cat(1,newlevel_x1,newlevel_x2,newlevel_x3,newlevel_x4));
            Enhanced = newlevel(I+1);
        end

        function [threshold] = ThresholdCorrectionSos01(app)
            I = app.Temp;
            nn = imhist(I);
            p = nn / sum(nn);
            c = cumsum(p);
            s1 = zeros([1 254]);
            s2 = zeros([1 254]);
            for i = 2:254
                a1 = sum(c(1:i));
                b1 = sum(c(i+1:end));
                a1 = (a1 + 1) .^ 1;
                b1 = (b1 + 1) .^ 1;
                s1(i) = a1 .* cos(log10(log10(a1)));
                s2(i) = b1 .* cos(log10(log10(b1)));
            end
            F = (s1 + s2);
            f = F(2:end);
            threshold = find(f==max(f(:)));
        end

        function [Enhanced_RGB,Enhanced_NIR,I] = Sensor_Based_Enhancement(app)
            RGB = app.Fused;
            NIR = app.grayscale;
            [x,y] = size(NIR);
            X = [1 2;3 4];
            B = repmat(X,[x y]);
            I = uint8(B);
            I(B==1) = RGB(:,:,1);
            I(B==2) = RGB(:,:,2);
            I(B==3) = RGB(:,:,3);
            I(B==4) = NIR(:,:,1);
            J = adapthisteq(I,'clipLimit',0.005);
            Enhanced_RGB = RGB;
            for i = 1:3
                V = J(B==i);
                V = reshape(V,x,y);
                Enhanced_RGB(:,:,i) = V;
            end
            Enhanced_NIR = J(B==4);
            Enhanced_NIR = reshape(Enhanced_NIR,x,y);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseRGB
        function BrowseRGBButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.png';'*.tiff'}, 'Select file');
            app.PathRGB.Text = [path,file];
            app.img = imread([path,file]);
            imshow(app.img,'Parent',app.Figure1)
            app.Process.Enable = "on";
            app.Filter = 'Gaussian';
            app.Kernel = 3;
            app.Sigma  = 1;
            app.alpha  = 10;
            app.beta   = 10;

        end

        % Value changing function: SigmaKnob
        function SigmaKnobValueChanging(app, event)
            changingValue = event.Value;
            app.SigmaEditField.Value = changingValue;
            app.Sigma = double(changingValue);
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            value = app.SigmaEditField.Value;
            app.SigmaKnob.Value = value;
            app.Sigma = double(value);
        end

        % Value changed function: KernelSizeKnob
        function KernelSizeKnobValueChanged(app, event)
            app.Kernel = app.KernelSizeKnob.Value;
            app.KernelLabel.Text = ['Kernel = ',num2str(app.Kernel)];
            app.Kernel = num2str(app.Kernel);
            app.Kernel = str2double(app.Kernel);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            app.Filter = value;
            switch value
                case 'Gaussian'
                    app.SigmaKnob.Enable = "on";
                    app.SigmaEditField.Enable = "on";
                    app.KernelSizeKnob.Enable = "on";
                otherwise
                    app.SigmaKnob.Enable = "off";
                    app.SigmaEditField.Enable = "off";
                    app.KernelSizeKnob.Enable = "on";
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AlphaEditField.Value = changingValue;
            app.alpha = changingValue;
        end

        % Value changing function: BetaSlider
        function BetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BetaEditField.Value = changingValue;
            app.beta = changingValue;
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditField.Value;
            app.AlphaSlider.Value = value;
            app.alpha = num2str(value);
            app.alpha = str2double(app.alpha);
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            value = app.BetaEditField.Value;
            app.BetaSlider.Value = value;
            app.beta = num2str(value);
            app.beta = str2double(app.beta);
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)

        end

        % Button pushed function: Process
        function ProcessButtonPushed(app, event)
            app.Save.Enable = "on";
            app.denoised = denoising(app);
            [app.Fused,app.Edge] = Retinex(app);
            [x,y,z] = size(app.Fused);
            app.Fused = reshape(app.Fused,x,y*z);
            app.Enhanced = QuadEqualizationSeperatedbyThresholdCorrection(app);
            app.Fused = reshape(app.Fused,x,y,z); 
            app.Enhanced = reshape(app.Enhanced,x,y,z);
            app.grayscale = rgb2gray(app.Enhanced);
            [app.Results,~,~] = Sensor_Based_Enhancement(app);
            imshow(app.denoised,'Parent',app.Figure2)
            imshow(app.Fused,'Parent',app.Figure3)
            imshow(app.Results,'Parent',app.Figure4)
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            [file,path] = uiputfile('Results.png');
            imwrite(app.Results, [path,file]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RetinexImageEnhancementUIFigure = uifigure('Visible', 'off');
            app.RetinexImageEnhancementUIFigure.Name = 'Retinex Image Enhancement';
            app.BacklitImageVisualizationUsingRetinexTheoryLabel = uilabel(app.RetinexImageEnhancementUIFigure);
            app.BacklitImageVisualizationUsingRetinexTheoryLabel.Text = 'Backlit Image Visualization Using Retinex Theory';
            app.TabGroup = uitabgroup(app.RetinexImageEnhancementUIFigure);
            app.ImplementationTab = uitab(app.TabGroup);
            app.ImplementationTab.Title = 'Implementation';
            app.InputPanel = uipanel(app.ImplementationTab);
            app.InputPanel.Title = 'Input';
            app.Figure1 = uiaxes(app.InputPanel);
            title(app.Figure1, 'Input image')
            app.Figure1.XTick = [];
            app.Figure1.YTick = [];
            app.Figure1.YTickLabel = '';
            app.PathRGB = uilabel(app.InputPanel);
            app.PathRGB.Text = 'File Path:';
            app.BrowseRGB = uibutton(app.InputPanel, 'push');
            app.BrowseRGB.ButtonPushedFcn = createCallbackFcn(app, @BrowseRGBButtonPushed, true);
            app.BrowseRGB.Text = 'Browse';
            app.Process = uibutton(app.InputPanel, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Process.Enable = 'off';
            app.Process.Text = 'Process';
            app.ParametersPanel = uipanel(app.ImplementationTab);
            app.ParametersPanel.Title = 'Parameters';
            app.FilterDropDownLabel = uilabel(app.ParametersPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.ParametersPanel);
            app.FilterDropDown.Items = {'Gaussian', 'Bilateral', 'Mean'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Gaussian';
            app.KernelSizeKnob = uiknob(app.ParametersPanel, 'discrete');
            app.KernelSizeKnob.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19'};
            app.KernelSizeKnob.ValueChangedFcn = createCallbackFcn(app, @KernelSizeKnobValueChanged, true);
            app.KernelSizeKnob.Value = '3';
            app.SigmaKnob = uiknob(app.ParametersPanel, 'continuous');
            app.SigmaKnob.Limits = [0 5];
            app.SigmaKnob.ValueChangingFcn = createCallbackFcn(app, @SigmaKnobValueChanging, true);
            app.SigmaKnob.Value = 1;
            app.SigmaEditFieldLabel = uilabel(app.ParametersPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SigmaEditField.ValueDisplayFormat = '%.4f';
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Value = 1;
            app.KernelLabel = uilabel(app.ParametersPanel);
            app.KernelLabel.Text = 'Kernel = 3';
            app.AlphaSlider = uislider(app.ParametersPanel);
            app.AlphaSlider.Limits = [1 20];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.Value = 10;
            app.BetaSlider = uislider(app.ParametersPanel);
            app.BetaSlider.Limits = [1 20];
            app.BetaSlider.ValueChangingFcn = createCallbackFcn(app, @BetaSliderValueChanging, true);
            app.BetaSlider.Value = 10;
            app.AlphaEditFieldLabel = uilabel(app.ParametersPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AlphaEditField.Limits = [1 20];
            app.AlphaEditField.ValueDisplayFormat = '%.4f';
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 10;
            app.BetaEditFieldLabel = uilabel(app.ParametersPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.BetaEditField.Limits = [1 20];
            app.BetaEditField.ValueDisplayFormat = '%.4f';
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.BetaEditField.Value = 10;
            app.OutputPanel = uipanel(app.ImplementationTab);
            app.OutputPanel.Title = 'Output';
            app.Figure2 = uiaxes(app.OutputPanel);
            title(app.Figure2, 'Denoised image')
            app.Figure2.XTick = [];
            app.Figure2.YTick = [];
            app.Figure2.YTickLabel = '';
            app.Figure3 = uiaxes(app.OutputPanel);
            title(app.Figure3, 'Retinex image')
            app.Figure3.XTick = [];
            app.Figure3.YTick = [];
            app.Figure3.YTickLabel = '';
            app.Figure4 = uiaxes(app.OutputPanel);
            title(app.Figure4, 'Output image')
            app.Figure4.XTick = [];
            app.Figure4.YTick = [];
            app.Figure4.YTickLabel = '';
            app.Save = uibutton(app.OutputPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Enable = 'off';
            app.Save.Text = 'Save a resulting image';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.PeoplePanel = uipanel(app.AboutTab);
            app.PeoplePanel.Title = 'People';
            app.Image = uiimage(app.PeoplePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'People01.jpg');
            app.Image_2 = uiimage(app.PeoplePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'People02.jpg');
            app.Image_3 = uiimage(app.PeoplePanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'People03.jpeg');
            app.Image_4 = uiimage(app.PeoplePanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'People04.jpg');
            app.DistinguishedProfSosAgaiandLabel = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel.Text = {'Prof. Sos Agaian'; 'Distinguished Professor of Computer Science'; 'Foreign Member of Armenian National Academy'; 'IEEE Fellow, SPIE Fellow, AAAS Fellow, IS&T Fellow'; 'Computer Science Department,The College of Staten Island, and'; 'Graduate Center, City University of New York (CUNY)'; 'E-mail: sos.agaian@csi.cuny.edu'};
            app.DistinguishedProfSosAgaiandLabel_2 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_2.Text = {'Thaweesak Trongtirakul, D.Eng.'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'SPIE Member'; 'E-mail: thaweesak.tr@rmutp.ac.th'};
            app.DistinguishedProfSosAgaiandLabel_3 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_3.Text = {'Prof. Adel Oulfki'; 'Centre de Développement des Technologies Avancées'; 'SPIE Member'; 'E-mail: adel.oulefki@fulbrightmail.org'};
            app.DistinguishedProfSosAgaiandLabel_4 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_4.Text = {'Naruepon Petrungtrakun'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: naruepon-p@rmutp.ac.th'; 'Tel: +66980349485'};
            app.Image_5 = uiimage(app.PeoplePanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'People05.jpg');
            app.Image_6 = uiimage(app.PeoplePanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'People06.jpg');
            app.DistinguishedProfSosAgaiandLabel_5 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_5.Text = {'Poomin Yenjam'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: poomin-y@rmutp.ac.th'; 'Tel: +66961814345'};
            app.DistinguishedProfSosAgaiandLabel_6 = uilabel(app.PeoplePanel);
            app.DistinguishedProfSosAgaiandLabel_6.Text = {'Thanaphat Phromthip'; 'Electrical Engineering, Industrual Education, '; 'Rajamangala University of Technology Phra Nakhon (RMUTP)'; 'Project Member'; 'E-mail: thanaphat-phr@rmutp.ac.th'; 'Tel: +66945890325'};
            app.VersionHistoryPanel = uipanel(app.AboutTab);
            app.VersionHistoryPanel.Title = 'Version History';
            app.DistinguishedProfSosAgaiandLabel_7 = uilabel(app.VersionHistoryPanel);
            app.DistinguishedProfSosAgaiandLabel_7.Text = {'Version 3.0.0'; '- Add a new enhancement technique on a synthetic infrared imaging component. '; '- Bug fixes and performance improvements.'; ''; 'Version 2.0.0'; '- Two additional color-spaces added, bug fixes and performance improvements.'; ''; 'Version 1.0.0'; '- Lunch a new framework for multispectral imagery.'};
            app.DescriptionPanel = uipanel(app.AboutTab);
            app.DescriptionPanel.Title = 'Description';
            app.DistinguishedProfSosAgaiandLabel_8 = uilabel(app.DescriptionPanel);
            app.DistinguishedProfSosAgaiandLabel_8.Text = {'Visible images illustrate chominance information with high-spatial resolution and definition in a manner consistent with the human visual system. However, color images suffer from nighttime conditions due to insufficient luminance and reflectance. On the other hand, infrared images deliver disthingish details based on the radiation difference, which operates well in all-weather and all-day/night conditions. Therefore, it is reasonable to integrate these two types of images, which can fuse the advantages of thermal radiation-dependent information in infrared imagery and chominance details in visible imagery.'; ''; 'In this work, we comprehensively build the image visualization method for visible and infrared images with a Graphic User Interface (GUI) application. Also, we provide the selection of a color-space in fusing thermal radiation-dependent information into chominance color imaging components.'; ''; 'Finally, we hope you enjoy working on our multispectral-imagery illustrative application.'; ''; 'Best,'};
            % Show the figure after all components are created
            app.RetinexImageEnhancementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RetinexImageEnhancementUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetinexImageEnhancementUIFigure)
        end
    end
end